classdef data_fetch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StopButton_3      matlab.ui.control.Button
        PlotlyLabel       matlab.ui.control.Label
        StopButton_2      matlab.ui.control.Button
        StartButton_2     matlab.ui.control.Button
        DataFetcherLabel  matlab.ui.control.Label
        StopAllButton     matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            ENTSOETimer = timer('Tag', 'ENTSOETimer') ;
            set(ENTSOETimer,'executionMode','fixedRate', "Period", 180, "TimerFcn", @called_by_timer)
            start(ENTSOETimer) ;
        end

        % Button pushed function: StopAllButton
        function StopAllButtonPushed(app, event)
            listOfTimers = timerfindall ;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            PlotlyTimer = timer('Tag', 'PlotlyTimer') ;
            set(PlotlyTimer,'executionMode','fixedRate', "Period", 15*60, "TimerFcn", @testing_refresh_byfuel)
            start(PlotlyTimer) ;
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            try
                delete(ENTSOETimer) ;
            catch ME
                return;
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            try
                delete(PlotlyTimer)
            catch ME
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopAllButton = uibutton(app.UIFigure, 'push');
            app.StopAllButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllButtonPushed, true);
            app.StopAllButton.Text = 'Stop All';
            app.DataFetcherLabel = uilabel(app.UIFigure);
            app.DataFetcherLabel.Text = 'Data Fetcher';
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.PlotlyLabel = uilabel(app.UIFigure);
            app.PlotlyLabel.Text = 'Plotly';
            app.StopButton_3 = uibutton(app.UIFigure, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_fetch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end