classdef emission_intensity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure   matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Panel2_5                  matlab.ui.container.Panel
        CategoryDropDown          matlab.ui.control.DropDown
        CategoryDropDownLabel     matlab.ui.control.Label
        DataSelectionLabel        matlab.ui.control.Label
        Panel2_4                  matlab.ui.container.Panel
        CountrynameDropDown       matlab.ui.control.DropDown
        CountrynameDropDownLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        PlotTab                   matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        DataTab                   matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
        EmissionsCategory
        Emissionsdatabase
        Power
        Powerload   
    end
    
    methods (Access = private)
        
        function NBins = numhistbins(app,data)
            % Utility function to compute the number of histogram bins 
            binwidth = app.BinWidth;
            range2plot =  floor(min(data)):binwidth:ceil(max(data));
            NBins = size(range2plot,2);
        end
        
        function annotateScatterPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Weight';
            app.UIAxes.YLabel.String = app.BloodPressureSwitch.Value;
            % Dont show the histogram slider
            app.BinWidthSliderLabel.Visible = 'off';
            app.BinWidthSlider.Visible = 'off';
        end
        
        function annotateHistogram(app)
           
            % Update X and Y Labels
            app.UIAxes.XLabel.String = app.BloodPressureSwitch.Value;
            app.UIAxes.YLabel.String = '# of Patients';
            
            % Show histogram slider
            app.BinWidthSliderLabel.Visible = 'on';
            app.BinWidthSlider.Visible = 'on';
        end
        
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the smoker control
            if app.NoCheckBox.Value && ~app.YesCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 0];
            elseif app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, app.Data.Smoker == 1];
            elseif  ~app.YesCheckBox.Value && ~app.NoCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the gender control
            if app.MaleCheckBox.Value && ~app.FemaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Male"];
            elseif app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, app.Data.Gender == "Female"];
            elseif  ~app.FemaleCheckBox.Value && ~app.MaleCheckBox.Value
                tempIndices = [tempIndices, zeros([size(app.Data,1),1])];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the location control
            if app.CountrynameDropDown.Value ~= "All"
                tempIndices = [tempIndices, app.Data.Location == string(app.CountrynameDropDown.Value)];
            end
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            app.CountrynameDropDown.Items = country2fetch ;
            app.EmissionsCategory = 'GlobalWarming'     ;
            app.Emissionsdatabase = load_emissions      ;    
            app.Powerload = load('testing_xchangev2.mat') ;
            app.Power = app.Powerload ;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {402, 402};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Cell selection callback: UITable
        function refreshplot(app, event)
            cla(app.UIAxes) ;
            switch app.CategoryDropDown.Value
                case 'Emissions'
                    [emout,subDB] = compareEmData(app.EmissionsCategory, app.Emissionsdatabase, app.CountrynameDropDown.Value) ;
                    % Update the table to show only the data that satisfies the controls
                    app.UITable.Data = emout ;
                    X = categorical(emout(:,1));
                    bar(app.UIAxes,X, cell2mat(emout(:,2:end))) ;
                    app.UIAxes.XAxis.Categories = X ;
                    legend(app.UIAxes,subDB)
                case 'Power'
                    [emout,subDB] = comparePowerData(app.CountrynameDropDown.Value) ;
                    % Update the table to show only the data that satisfies the controls
                    app.UITable.Data = emout ;
                    X = categorical(emout(:,1));
                    bar(app.UIAxes, X, cell2mat(emout(:,2:end))') ;
                    app.UIAxes.XAxis.Categories = X ;
                    legend(app.UIAxes,subDB)
                    
            end
            
        end

        % Value changed function: CategoryDropDown, CountrynameDropDown
        function updateSelectedGenders(app, event)
            % List which genders and colors to use
            refreshplot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Location';
            app.CountrynameDropDownLabel = uilabel(app.Panel2_4);
            app.CountrynameDropDownLabel.Text = 'Country name';
            app.CountrynameDropDown = uidropdown(app.Panel2_4);
            app.CountrynameDropDown.Items = {};
            app.CountrynameDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.CountrynameDropDown.Value = {};
            app.DataSelectionLabel = uilabel(app.LeftPanel);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Type';
            app.CategoryDropDownLabel = uilabel(app.Panel2_5);
            app.CategoryDropDownLabel.Text = 'Category';
            app.CategoryDropDown = uidropdown(app.Panel2_5);
            app.CategoryDropDown.Items = {'Emissions', 'Power'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.CategoryDropDown.Value = 'Emissions';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            xlabel(app.UIAxes, 'Category')
            ylabel(app.UIAxes, 'Emission factor')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Last Name'; 'Gender'; 'Smoker'; 'Age'; 'Height'; 'Weight'; 'Diastolic'; 'Systolic'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emission_intensity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end